classdef DGADashboard < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIAxesTemperature        matlab.ui.control.UIAxes
    SimGrid                  matlab.ui.container.GridLayout
    SimTab                   matlab.ui.container.Tab
    ModelLabel               matlab.ui.control.Label
    ProbabilityTable         matlab.ui.control.Table
    ResultsTable             matlab.ui.control.Table
    TablesGrid               matlab.ui.container.GridLayout
    TablesTab                matlab.ui.container.Tab
    ExplainArea              matlab.ui.control.TextArea
    ExplainGrid              matlab.ui.container.GridLayout
    ExplainTab               matlab.ui.container.Tab
    StepsArea                matlab.ui.control.TextArea
    StepsGrid                matlab.ui.container.GridLayout
    StepsTab                 matlab.ui.container.Tab
    ProbAxes                 matlab.ui.control.UIAxes
    UIAxesDuval              matlab.ui.control.UIAxes
    GasAxes                  matlab.ui.control.UIAxes
    ChartsGrid               matlab.ui.container.GridLayout
    ChartsTab                matlab.ui.container.Tab
    MainTabs                 matlab.ui.container.TabGroup
    EventTable               matlab.ui.control.Table
    RefJudge                 matlab.ui.control.Label
    RefNote                  matlab.ui.control.Label
    RefAnswer                matlab.ui.control.Label
    RefGrid                  matlab.ui.container.GridLayout
    RefCard                  matlab.ui.container.Panel
    MLJudge                  matlab.ui.control.Label
    MLNote                   matlab.ui.control.Label
    MLAnswer                 matlab.ui.control.Label
    MLGrid                   matlab.ui.container.GridLayout
    MLCard                   matlab.ui.container.Panel
    DuvalJudge               matlab.ui.control.Label
    DuvalNote                matlab.ui.control.Label
    DuvalAnswer              matlab.ui.control.Label
    DuvalGrid                matlab.ui.container.GridLayout
    DuvalCard                matlab.ui.container.Panel
    IECJudge                 matlab.ui.control.Label
    IECNote                  matlab.ui.control.Label
    IECAnswer                matlab.ui.control.Label
    IECGrid                  matlab.ui.container.GridLayout
    IECCard                  matlab.ui.container.Panel
    RogersJudge              matlab.ui.control.Label
    RogersNote               matlab.ui.control.Label
    RogersAnswer             matlab.ui.control.Label
    RogersGrid               matlab.ui.container.GridLayout
    RogersCard               matlab.ui.container.Panel
    CardsGrid                matlab.ui.container.GridLayout
    AgreementLabel           matlab.ui.control.Label
    AgreementLamp            matlab.ui.control.Lamp
    AgreementGrid            matlab.ui.container.GridLayout
    VerdictAction            matlab.ui.control.Label
    VerdictDetail            matlab.ui.control.Label
    VerdictLabel             matlab.ui.control.Label
    VerdictGrid              matlab.ui.container.GridLayout
    VerdictPanel             matlab.ui.container.Panel
    RightGrid                matlab.ui.container.GridLayout
    StatusLabel              matlab.ui.control.Label
    RestartServiceButton     matlab.ui.control.Button
    DiagnoseValuesButton     matlab.ui.control.Button
    GasTable                 matlab.ui.control.Table
    DiagnoseButton           matlab.ui.control.Button
    RecordSpinner            matlab.ui.control.Spinner
    RecordSpinnerLabel       matlab.ui.control.Label
    RandomRecordButton       matlab.ui.control.Button
    FaultFilterDropDown      matlab.ui.control.DropDown
    LoadDGAFileButton        matlab.ui.control.Button
    Step3Label               matlab.ui.control.Label
    RunSimulationButton      matlab.ui.control.Button
    SampleSetDropDown        matlab.ui.control.DropDown
    Step2Label               matlab.ui.control.Label
    ScenarioDropDown         matlab.ui.control.DropDown
    Step1Label               matlab.ui.control.Label
    ControlGrid              matlab.ui.container.GridLayout
    ControlPanel             matlab.ui.container.Panel
    GridLayout               matlab.ui.container.GridLayout
    UIFigure                 matlab.ui.Figure
  end

    properties (Access = public)
        DGAFile        % full path of the measured DGA file
        ServiceURL     % address of the FastAPI service (D37)
        LastResult     % struct of the last diagnosis: record_id, gas, conventional, ml, agreement, verdict (D37, RD)
        SimResult      % struct returned by run_transformer_simulation (public so that the checks can compare it) (MC)
        SimEvents      % table of the sampling events of the last simulation: time_h, record_index, record_id (D38)
        EventResults   % struct array, one element per sampling event diagnosed in the last run (D38)
        PlaybackPause = 0.5  % pause between sampling events in the playback, s (0 in tests) (D38)
        ExplainState   % struct with the explanation texts: sim, diag, events (string columns), focus ("diag" or "run") (MC)
        ProjRoot       % folder that holds project_setup.m (MC)
        ServiceStarted = false  % true when THIS app started the ML service; only then it stops it when it closes (MC)
        SampleSeed = 1  % seed of the next random choice of records; it grows by 1 after every random choice (RD)
        Chosen         % struct rows / record_id / label of the samples of the current run (RD)
    end

    properties (Access = private)
        Gas        % struct with H2, CH4, C2H6, C2H4, C2H2 of the current record
        Info        % table row of the current record (traceability)
    end

    methods (Access = private)

        % Lamp and text of the agreement flag (Build Guide C4.5; rule in dga_agreement.m, D37)
        function updateAgreement(app, a)
            app.AgreementLamp.Color = a.color;
            app.AgreementLabel.Text = a.label;
        end

        % Text of the status line and of a progress dialog while a long step runs (MC)
        function setProgress(app, dlg, txt)
            app.StatusLabel.Text = txt;
            if ~isempty(dlg) && isvalid(dlg), dlg.Message = txt; end
            drawnow limitrate;
        end

        % Switch the buttons off while a long step runs, so that a second click cannot start a second step (MC, RD)
        function setBusy(app, tf)
            if ~isvalid(app), return, end
            st = 'on'; if tf, st = 'off'; end
            app.RunSimulationButton.Enable = st;  app.LoadDGAFileButton.Enable = st;  app.DiagnoseButton.Enable = st;
            app.RestartServiceButton.Enable = st;  app.RecordSpinner.Enable = st;
            app.DiagnoseValuesButton.Enable = st;  app.ScenarioDropDown.Enable = st;  app.SampleSetDropDown.Enable = st;  app.FaultFilterDropDown.Enable = st;  app.RandomRecordButton.Enable = st;
            drawnow;
        end

        % Show the explanation text of the current state in the right-hand panel (MC)
        function refreshExplanation(app)
            app.ExplainArea.Value = cellstr(dashboard_explain('all', app.ExplainState));
            try, scroll(app.ExplainArea, 'top'); catch, end
        end

        % Is the local ML service answering? If not, (re)start it before the call. A service that hangs is stopped and started again (MC)
        function ok = mlReady(app)
            ok = true;
            if ~contains(app.ServiceURL, '127.0.0.1:8000'), return, end    % another address: nothing to manage
            try
                r = webread('http://127.0.0.1:8000/health', weboptions('Timeout', 2));
                ok = isstruct(r);
            catch
                ok = false;
            end
            if ~ok
                [ok, ~, started] = ensure_ml_service(app.ProjRoot, 60, @(s) app.setProgress([], s));
                if started, app.ServiceStarted = true; end
            end
        end

        % The chart areas are always drawn on white with dark text, so that they read well in the light and in the dark theme of MATLAB (RD)
        function styleAxes(~, ax)
            ink = [0.15 0.15 0.15];
            ax.Color = [1 1 1];  ax.XColor = ink;  ax.YColor = ink;  ax.GridColor = [0.5 0.5 0.5];
            try, ax.Title.Color = ink; ax.XLabel.Color = ink; ax.YLabel.Color = ink; catch, end
        end

        % Colour of a banner or card: panel and its grid (a grid does not take the colour of its parent) (RD)
        function tint(app, comps, rgb)
            for c = comps(:)'
                c{1}.BackgroundColor = rgb;
            end
        end

        % Start screen of the verdict banner, the cards and the charts: nothing diagnosed yet (RD)
        function showIdle(app)
            grey = [0.45 0.45 0.45];
            app.tint({app.VerdictPanel, app.VerdictGrid, app.AgreementGrid}, grey);
            app.VerdictLabel.Text = 'WAITING FOR A SAMPLE';
            app.VerdictDetail.Text = 'Choose the operating condition and the samples on the left, then press "Run simulation + diagnose". Or pick one record with "Pick random record".';
            app.VerdictAction.Text = 'The answer appears here: HEALTHY (green), FAULT with its type (red), or WATCH / CHECK (amber).';
            cards = {'Rogers', 'IEC', 'Duval', 'ML', 'Ref'};
            for k = 1:numel(cards)
                app.([cards{k} 'Answer']).Text = '-';  app.([cards{k} 'Note']).Text = '';  app.([cards{k} 'Judge']).Text = '';
                app.tint({app.([cards{k} 'Card']), app.([cards{k} 'Grid'])}, [0.94 0.94 0.94]);
            end
            cla(app.GasAxes);  cla(app.ProbAxes);  cla(app.UIAxesDuval);
            title(app.GasAxes, 'Gas level compared with the typical value');  title(app.ProbAxes, 'ML probability of each fault type');
            app.styleAxes(app.GasAxes);  app.styleAxes(app.ProbAxes);  app.styleAxes(app.UIAxesTemperature);
            app.StepsArea.Value = {'The standard procedure for the sample on the screen appears here after a diagnosis.'};
        end

        % Banner: the answer in one line, why, and what to do (RD)
        function showVerdict(app, V)
            app.tint({app.VerdictPanel, app.VerdictGrid, app.AgreementGrid}, V.color);
            app.VerdictLabel.Text = char(V.title);
            app.VerdictDetail.Text = char(V.detail);
            app.VerdictAction.Text = char("What to do: " + V.action);
        end

        % The four method cards and the card of the known label: answer, plain meaning, AGREES / DIFFERS (RD)
        function showCards(app, V)
            cards = {'Rogers', 'IEC', 'Duval', 'ML'};
            for k = 1:4
                x = V.answers(k);  if x == "", x = "n/a"; end
                note = dashboard_explain('label', V.answers(k));
                if V.has_ref && V.answers(k) ~= ""
                    if V.ref_ok(k), note = note + newline + "Known label: right"; else, note = note + newline + "Known label: wrong"; end
                end
                app.([cards{k} 'Answer']).Text = char(x);
                app.([cards{k} 'Note']).Text = char(note);
                app.([cards{k} 'Judge']).Text = char(V.judge(k));
                switch V.judge(k)
                    case "AGREES",  col = [0.86 0.95 0.86];  fc = [0 0.4 0];
                    case "DIFFERS", col = [0.98 0.87 0.87];  fc = [0.7 0 0];
                    otherwise,      col = [0.93 0.93 0.93];  fc = [0.35 0.35 0.35];
                end
                app.tint({app.([cards{k} 'Card']), app.([cards{k} 'Grid'])}, col);
                app.([cards{k} 'Judge']).FontColor = fc;
            end
            if V.has_ref
                app.RefAnswer.Text = char(V.ref);
                app.RefNote.Text = char(dashboard_explain('label', V.ref));
                app.RefJudge.Text = 'from the data file';  app.RefJudge.FontColor = [0.1 0.1 0.1];
                app.tint({app.RefCard, app.RefGrid}, [0.88 0.92 0.98]);
            else
                app.RefAnswer.Text = '-';  app.RefNote.Text = 'This file has no known label for the record.';  app.RefJudge.Text = '';
                app.tint({app.RefCard, app.RefGrid}, [0.94 0.94 0.94]);
            end
        end

        % Bar chart: each gas as a multiple of its typical value (IEC 60599 Annex A); a bar above the dashed line is above typical (RD)
        function drawGas(app, V)
            ax = app.GasAxes;  cla(ax);
            G = V.gas;  y = max(G.times_typical, 0.01);
            b = bar(ax, 1:height(G), y, 'FaceColor', 'flat', 'BaseValue', 0.01);
            cd = repmat([0.2 0.6 0.3], height(G), 1);  cd(G.above, :) = repmat([0.8 0.2 0.2], sum(G.above), 1);
            b.CData = cd;
            ax.YScale = 'log';  ax.XTick = 1:height(G);  ax.XTickLabel = cellstr(G.gas);
            ylim(ax, [0.01 max(10, 5 * max(y))]);
            hold(ax, 'on');
            yline(ax, 1, 'k--', 'typical value', 'LabelHorizontalAlignment', 'right');
            for i = 1:height(G)
                text(ax, i, y(i), sprintf('%.8g ppm', G.ppm(i)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom', 'FontSize', 9);
            end
            hold(ax, 'off');
            ylabel(ax, 'times the typical value');
            title(ax, 'Gas level (red = above typical value)');
            app.styleAxes(ax);
            for tx = findobj(ax, 'Type', 'text')', tx.Color = [0.1 0.1 0.1]; end
        end

        % Bar chart: probability of each fault type according to the ML model (RD)
        function drawProb(app, m)
            ax = app.ProbAxes;  cla(ax);
            if m.status ~= "OK"
                text(ax, 0.5, 0.5, {'ML not available'; char(m.status)}, 'HorizontalAlignment', 'center', 'Units', 'normalized');
                ax.XTick = [];  ax.YTick = [];  title(ax, 'ML probability of each fault type');  app.styleAxes(ax);
                for tx = findobj(ax, 'Type', 'text')', tx.Color = [0.1 0.1 0.1]; end
                return
            end
            order = ["Normal" "PD" "D1" "D2" "T1" "T2" "T3"];
            p = zeros(1, 7);
            for i = 1:7, j = find(string(m.classes) == order(i), 1); if ~isempty(j), p(i) = m.probabilities(j); end, end
            b = barh(ax, 1:7, 100 * p, 'FaceColor', 'flat');
            cd = repmat([0.55 0.65 0.8], 7, 1);  [~, im] = max(p);  cd(im, :) = [0.1 0.3 0.7];
            b.CData = cd;
            ax.YTick = 1:7;  ax.YTickLabel = cellstr(order);  ax.YDir = 'reverse';  xlim(ax, [0 100]);
            xlabel(ax, 'probability (%)');
            title(ax, 'ML probability of each fault type');
            app.styleAxes(ax);
        end

        % Show one finished diagnosis on the screen: verdict banner, method cards, charts, tables, steps and the explanation text (MC, RD)
        function showResult(app, R, row)
            g = R.gas;  d = R.conventional;  m = R.ml;  a = R.agreement;
            app.GasTable.Data = table(["H2"; "CH4"; "C2H6"; "C2H4"; "C2H2"], ...
                [g.H2; g.CH4; g.C2H6; g.C2H4; g.C2H2], 'VariableNames', {'Gas', 'ppm'});
            mlText = m.status;
            if m.status == "OK", mlText = m.fault_class; end
            yesno = repmat("-", 3, 1);
            if m.status == "OK", yesno(a.agrees) = "yes"; yesno(~a.agrees) = "no"; end
            app.ResultsTable.Data = table(["Rogers Ratio"; "IEC 60599 ratio"; "Duval Triangle 1"; "Machine learning"], ...
                [string(d.rogers); string(d.iec); string(d.duval); mlText], [yesno; "-"], ...
                'VariableNames', {'Method', 'Diagnosis', 'Agrees with ML'});
            if m.status == "OK"
                app.ProbabilityTable.Data = table(m.classes(:), m.probabilities(:), 'VariableNames', {'Class', 'Probability'});
                app.ModelLabel.Text = sprintf('ML: %s v%s (hash %s), confidence %.3f, %.0f ms', ...
                    m.model_name, m.model_version, extractBefore(m.model_sha256, 9), m.confidence, 1000 * m.latency_s);
            else
                app.ProbabilityTable.Data = table(strings(0, 1), zeros(0, 1), 'VariableNames', {'Class', 'Probability'});
                app.ModelLabel.Text = ['ML: ' char(m.status)];
            end
            try
                plot_duval_triangle(app.UIAxesDuval, d.ratios, fullfile(app.ProjRoot, '02_Standards', 'duval_triangle_zones.csv'));
                if d.gate_upper(1)
                    title(app.UIAxesDuval, 'Duval Triangle 1 (not used: gas level normal)');
                else
                    title(app.UIAxesDuval, 'Duval Triangle 1 (this sample)');
                end
                app.styleAxes(app.UIAxesDuval);
            catch ME
                cla(app.UIAxesDuval);
                app.StatusLabel.Text = ['Duval Triangle not drawn: ' ME.message];
            end
            app.updateAgreement(a);
            V = R.verdict;
            app.showVerdict(V);
            app.showCards(V);
            app.drawGas(V);
            app.drawProb(m);
            app.StepsArea.Value = cellstr(dashboard_explain('steps', g, d, m, a, V));
            app.ExplainState.diag = dashboard_explain('diagnosis', g, d, m, a);
            app.refreshExplanation();
        end

        % Diagnose one row of the loaded DGA file: the one code path of the Diagnose button, the random picker and the sampling events (D38, RD)
        function ok = diagnoseRow(app, row)
            ok = false;
            try
                [app.Gas, app.Info] = load_dga_record(app.DGAFile, row);
            catch ME
                app.StatusLabel.Text = ['Cannot read the record: ' ME.message];
                return
            end
            ref = "";                                      % known label of the record, only if the file has one (used for the right/wrong note, never for a diagnosis)
            if ismember('class_name', app.Info.Properties.VariableNames), ref = string(app.Info.class_name(1)); end
            ok = app.diagnoseGas(app.Gas, ref, row);
        end

        % Diagnose one set of five gas values (from a file row or typed in the table): conventional methods, ML, agreement, verdict, screen (RD)
        function ok = diagnoseGas(app, g, ref, row)
            ok = false;
            app.StatusLabel.Text = 'Diagnosing...'; drawnow;
            d = conventional_diagnosis(g);                 % three conventional diagnoses, computed here in MATLAB
            app.mlReady();                                 % the service answers, or it is restarted (MC)
            m = ml_diagnose(g, app.ServiceURL);            % ML diagnosis from the FastAPI service (webwrite)
            if m.status == "NOT_CONNECTED" && contains(app.ServiceURL, ':8000/')   % stopped or hung during the call: restart once and retry
                app.StatusLabel.Text = 'ML service not reachable: starting it...'; drawnow;
                [okS, ~, started] = ensure_ml_service(app.ProjRoot, 60, @(s) app.setProgress([], s));
                if started, app.ServiceStarted = true; end
                if okS, m = ml_diagnose(g, app.ServiceURL); end
            end
            a = dga_agreement(d, m);
            rid = ""; if isfield(g, 'record_id'), rid = g.record_id; end
            V = dashboard_verdict(g, d, m, a, ref);
            app.LastResult = struct('record_id', rid, 'gas', g, 'conventional', d, 'ml', m, 'agreement', a, 'verdict', V);
            app.ExplainState.focus = "diag";
            app.showResult(app.LastResult, row);
            if startsWith(app.StatusLabel.Text, 'Diagnosing')
                if row > 0, app.StatusLabel.Text = sprintf('Record %d %s diagnosed.', row, char(rid));
                else, app.StatusLabel.Text = 'The typed gas values were diagnosed.';
                end
            end
            ok = true;
        end

        % Sampling times of a run with n oil samples, in hours from the start of the shown 24 h (n = 4 is the Step 40 run) (RD)
        function th = sampleTimesH(~, n)
            if n == 4, th = [6 12 18 22]; else, th = (1:n) * 24 / (n + 1); end
        end

        % Play the sampling events of the last simulation back in time order and diagnose the record of each (D38, RD)
        function playEvents(app, baseText)
            ev = app.SimEvents;  r = app.SimResult;
            app.EventResults = struct('event', {}, 'time_h', {}, 'record_id', {}, 'row', {}, 'hot_spot_C', {}, 'result', {});
            vn = {'Event', 'Time (h)', 'Record', 'Known label', 'Rogers', 'IEC 60599', 'Duval', 'ML', 'Agree (of 3)', 'Verdict', 'Hot-spot (degC)'};
            tbl = table(zeros(0, 1), zeros(0, 1), strings(0, 1), strings(0, 1), strings(0, 1), strings(0, 1), strings(0, 1), strings(0, 1), strings(0, 1), strings(0, 1), zeros(0, 1), 'VariableNames', vn);
            app.EventTable.Data = tbl;
            if isempty(ev)
                app.StatusLabel.Text = [baseText ' No sampling events in this run.'];
                return
            end
            nrec = height(readtable(app.DGAFile));
            app.RecordSpinner.Limits = [1 nrec];
            nok = 0;
            for k = 1:height(ev)
                idx = ev.record_index(k);
                if idx > numel(app.Chosen.rows), continue, end
                row = app.Chosen.rows(idx);  rid = app.Chosen.record_id(idx);
                if isnan(row)
                    app.StatusLabel.Text = sprintf('Event %d: record %s is not in the DGA file.', k, char(rid));
                    continue
                end
                if rid == "", rid = "row " + row; end
                app.RecordSpinner.Value = row;
                app.StatusLabel.Text = sprintf('Sampling event %d at %.1f h: diagnosing %s...', k, ev.time_h(k), char(rid)); drawnow;
                if ~app.diagnoseRow(row), continue, end
                R = app.LastResult;  hs = interp1(r.t_h, r.theta_hs_C, ev.time_h(k));
                mlText = R.ml.status;  agr = "-";
                if R.ml.status == "OK", mlText = R.ml.fault_class; agr = string(R.agreement.count); end
                V = R.verdict;
                if V.health == "fault", vt = "FAULT " + V.fault_class;
                elseif V.health == "healthy", vt = "HEALTHY";
                elseif V.health == "watch", vt = "WATCH";
                else, vt = "CHECK";
                end
                lab = app.Chosen.label(idx);  if lab == "", lab = "-"; end
                tbl(end + 1, :) = {k, ev.time_h(k), rid, lab, string(R.conventional.rogers), string(R.conventional.iec), string(R.conventional.duval), mlText, agr, vt, hs}; %#ok<AGROW>
                app.EventTable.Data = tbl;
                app.EventResults(end + 1) = struct('event', k, 'time_h', ev.time_h(k), 'record_id', rid, 'row', row, 'hot_spot_C', hs, 'result', R);
                nok = nok + 1;
                pause(app.PlaybackPause);
            end
            app.StatusLabel.Text = sprintf('%s %d of %d sampling events diagnosed.', baseText, nok, height(ev));
            if ~isempty(app.EventResults)       % how many of each state, so that the mix of the run is visible at once
                hl = arrayfun(@(e) string(e.result.verdict.health), app.EventResults);
                app.StatusLabel.Text = [app.StatusLabel.Text sprintf(' Verdicts: %d healthy, %d fault, %d watch/check.', sum(hl == "healthy"), sum(hl == "fault"), sum(hl == "watch" | hl == "undecided"))];
            end
            app.ExplainState.events = dashboard_explain('events', app.EventResults);   % summary of the run first, then the last record (MC)
            app.ExplainState.focus = "run";
            app.refreshExplanation();
        end
    end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      % The app finds the project folders itself, so it also starts when project_setup has not been run (15 Oct 2026).
      % It looks upwards from the app file, then from the current folder, for the folder that holds project_setup.m.
      projRoot = '';
      starts = {};
      try, starts{end + 1} = fileparts(which('DGADashboard')); catch, end
      try, starts{end + 1} = fileparts(mfilename('fullpath')); catch, end
      starts{end + 1} = pwd;
      for iStart = 1:numel(starts)
          dirNow = starts{iStart};
          while ~isempty(dirNow) && isempty(projRoot)
              if isfile(fullfile(dirNow, 'project_setup.m')), projRoot = dirNow; end
              parentDir = fileparts(dirNow);
              if strcmp(parentDir, dirNow), break, end
              dirNow = parentDir;
          end
          if ~isempty(projRoot), break, end
      end
      if isempty(projRoot)
          app.StatusLabel.Text = 'Project folder not found. In MATLAB open the project folder, run project_setup.m, then start the dashboard again.';
          error('DGADashboard:ProjectNotFound', 'project_setup.m was not found above the app file or the current folder.');
      end
      app.ProjRoot = projRoot;
      addpath(fullfile(projRoot, '04_MATLAB_Conventional'), fullfile(projRoot, '05_Simulink_Model'), fullfile(projRoot, '10_Dashboard_Client'));
      % Parameters of the transformer model into the base workspace (Build Guide C4.1)
      evalin('base', 'params_transformer;');
      app.ServiceURL = 'http://127.0.0.1:8000/diagnose';
      app.ExplainState = struct('sim', strings(0, 1), 'diag', strings(0, 1), 'events', strings(0, 1), 'focus', "diag");
      app.refreshExplanation();                                  % the welcome text: what the dashboard does and how to use it
      app.showIdle();                                            % banner, cards and charts before the first diagnosis (RD)
      app.GasTable.Data = table(["H2"; "CH4"; "C2H6"; "C2H4"; "C2H2"], zeros(5, 1), 'VariableNames', {'Gas', 'ppm'});   % editable: type values and press "Diagnose the values typed above"
      % The dashboard is the master control: it starts the ML service itself if it is not running (15 Oct 2026).
      % A progress dialog shows what happens, so that the window never looks frozen; the buttons stay off until the service is ready.
      app.setBusy(true);
      dlg = uiprogressdlg(app.UIFigure, 'Title', 'Starting the dashboard', 'Message', 'Checking the ML service...', 'Indeterminate', 'on');
      [mlOK, mlMsg, started] = ensure_ml_service(projRoot, 60, @(s) app.setProgress(dlg, s));
      app.ServiceStarted = started;
      % Warm-up of the Simulink/Simscape model: the first run in a MATLAB session compiles the model (about 50 s measured, 15 Oct 2026).
      % Doing it here, behind a progress message, means the first click on "Run simulation + diagnose" answers in seconds (no result is kept or shown).
      app.setProgress(dlg, 'Preparing the Simulink model (first start only, up to 1 minute)...');
      try
          run_transformer_simulation();
      catch ME
          app.StatusLabel.Text = ['Simulink model not ready: ' ME.message];
      end
      if isvalid(dlg), close(dlg); end
      app.setBusy(false);
      if startsWith(app.StatusLabel.Text, 'Simulink model not ready')
          app.StatusLabel.Text = [app.StatusLabel.Text ' ML: ' mlMsg];
      elseif mlOK
          app.StatusLabel.Text = 'Ready. ML service connected. Press "Run simulation + diagnose" or pick a record.';
      else
          app.StatusLabel.Text = ['Ready, but ML NOT connected: ' mlMsg ' Use "Restart ML service" to try again.'];
      end
      app.AgreementLamp.Color = [0.6 0.6 0.6];
    end

    % Button pushed function: RunSimulationButton
    function RunSimulationButtonPushed(app, event)
      app.setBusy(true);  cleanBusy = onCleanup(@() app.setBusy(false));       % buttons off until the end of the run (MC)
      % 1 which samples: chosen BEFORE the simulation, so that the model gets the right number of sampling times (RD)
      usedDefault = isempty(app.DGAFile);
      if usedDefault, app.DGAFile = fullfile(app.ProjRoot, '03_Data', 'processed', 'dga_master.csv'); end
      kind = app.SampleSetDropDown.Value;
      try
          switch kind
              case 'mixed',  app.Chosen = dashboard_samples('mixed', app.DGAFile, 'Seed', app.SampleSeed);
              case 'healthy', app.Chosen = dashboard_samples('healthy', app.DGAFile, 'N', 7, 'Seed', app.SampleSeed);
              case 'random', app.Chosen = dashboard_samples('random', app.DGAFile, 'N', 7, 'Seed', app.SampleSeed);
              otherwise                                           % the Step 40 test run: the first four held-out records of the project list, looked up by record_id
                  ids4 = string(evalin('base', 'heldout_record_ids(1:4)'));  ids4 = ids4(:);
                  rows4 = arrayfun(@(q) find_dga_row(app.DGAFile, q), ids4);
                  app.Chosen = struct('rows', rows4, 'record_id', ids4, 'label', strings(4, 1));
          end
      catch ME
          app.StatusLabel.Text = ['Cannot choose samples: ' ME.message];
          return
      end
      nS = numel(app.Chosen.rows);
      setNo = app.SampleSeed;
      if any(strcmp(kind, {'mixed', 'healthy', 'random'})), app.SampleSeed = app.SampleSeed + 1; end
      % 2 which operating condition (scenarios.m, decision D34): demand profile, run length and the window that is shown (RD)
      scn = scenarios();  sc = scn(strcmp({scn.id}, app.ScenarioDropDown.Value));
      from_h = sc.show_from_s / 3600;
      opts = struct('t_stop_s', sc.t_stop_s, 't_sample_s', (from_h + app.sampleTimesH(nS)) * 3600);
      dlg = uiprogressdlg(app.UIFigure, 'Title', 'Simulation', 'Message', sprintf('Simulating %.0f h of transformer operation (%s)...', sc.t_stop_s / 3600, sc.label), 'Indeterminate', 'on');
      cleanDlg = onCleanup(@() delete(dlg));
      app.StatusLabel.Text = 'Simulating transformer operation...'; drawnow;
      try
          fullRes = run_transformer_simulation(sc.profile, opts);   % sim of Transformer_Operating_Model; also returns the sampling events (D38)
      catch ME
          app.StatusLabel.Text = ['Simulation failed: ' ME.message];
          return
      end
      r = sim_window(fullRes, from_h);                            % the shown 24 h (the last day for the daily cycle)
      app.SimResult = r;
      delete(findall(app.UIAxesTemperature, 'Type', 'constantline'));   % event lines of an earlier run (hidden handles are not cleared by plot)
      plot(app.UIAxesTemperature, r.t_h, r.theta_oil_C, 'b', r.t_h, r.theta_hs_C, 'r', 'LineWidth', 1.3);
      xlabel(app.UIAxesTemperature, 'Time (h)'); ylabel(app.UIAxesTemperature, 'Temperature (\circC)');
      lg = legend(app.UIAxesTemperature, 'Top-oil', 'Hot-spot', 'Location', 'southeast');  lg.TextColor = [0.15 0.15 0.15];  lg.Color = [1 1 1];
      app.styleAxes(app.UIAxesTemperature);
      title(app.UIAxesTemperature, ['Operating condition: ' sc.label ' (simulated, context only)']);
      grid(app.UIAxesTemperature, 'on');
      app.SimEvents = r.events;
      for k = 1:height(r.events)          % sampling events of SS8, numbered in time order
          xline(app.UIAxesTemperature, r.events.time_h(k), ':', char("#" + k), 'HandleVisibility', 'off', 'Color', [0.35 0.35 0.35]);
      end
      baseText = sprintf('Simulation done (%s). Final hot-spot %.1f degC, ageing %.1f h. (Operating context only.)', sc.label, ...
          r.theta_hs_C(end), r.loss_of_life_h);
      if any(strcmp(kind, {'mixed', 'healthy', 'random'})), baseText = [baseText sprintf(' Sample set no. %d.', setNo)]; end
      if usedDefault, baseText = [baseText ' (No DGA file loaded: using dga_master.csv.)']; end
      app.StatusLabel.Text = baseText; drawnow;
      P = struct('theta_ref_age', evalin('base', 'theta_ref_age'), 'dT_double_age', evalin('base', 'dT_double_age'), 'theta_amb_C', evalin('base', 'theta_amb_C'));
      simText = dashboard_explain('simulation', r, P);
      simText = [simText(1); "Operating condition: " + string(sc.label) + "."; simText(2:end)];
      app.ExplainState = struct('sim', simText, 'diag', strings(0, 1), 'events', strings(0, 1), 'focus', "run");
      app.refreshExplanation();
      if isvalid(dlg), dlg.Message = 'Diagnosing the sampling events...'; end
      app.playEvents(baseText);
    end

    % Button pushed function: LoadDGAFileButton
    function LoadDGAFileButtonPushed(app, event)
      [f, p] = uigetfile({'*.csv;*.xlsx', 'DGA files'}, 'Select measured DGA file', fullfile(app.ProjRoot, '03_Data', 'processed'));
      if isequal(f, 0), return, end
      try
          n = height(readtable(fullfile(p, f)));
      catch ME
          app.StatusLabel.Text = ['Cannot read the file: ' ME.message];
          return
      end
      app.DGAFile = fullfile(p, f);
      app.RecordSpinner.Limits = [1 n];
      app.RecordSpinner.Value = 1;
      app.StatusLabel.Text = sprintf('Loaded %s (%d records).', f, n);
    end

    % Button pushed function: DiagnoseButton
    function DiagnoseButtonPushed(app, event)
      if isempty(app.DGAFile)
          app.StatusLabel.Text = 'Load a DGA file first.'; return
      end
      app.setBusy(true);  cleanBusy = onCleanup(@() app.setBusy(false));
      app.diagnoseRow(app.RecordSpinner.Value);
    end

    % Button pushed function: RandomRecordButton
    function RandomRecordButtonPushed(app, event)
      % Pick a random record of the chosen type (healthy or one fault type) from the held-out records and diagnose it (RD)
      if isempty(app.DGAFile), app.DGAFile = fullfile(app.ProjRoot, '03_Data', 'processed', 'dga_master.csv'); end
      app.setBusy(true);  cleanBusy = onCleanup(@() app.setBusy(false));
      want = app.FaultFilterDropDown.Value;
      try
          S = dashboard_samples('class', app.DGAFile, 'Class', string(want), 'Seed', app.SampleSeed);
          nrec = height(readtable(app.DGAFile));
      catch ME
          app.StatusLabel.Text = ['Cannot choose a record: ' ME.message];
          return
      end
      app.SampleSeed = app.SampleSeed + 1;
      if isempty(S.rows)
          app.StatusLabel.Text = ['No held-out record of type ' want ' in this file.'];
          return
      end
      app.RecordSpinner.Limits = [1 nrec];
      app.RecordSpinner.Value = S.rows;
      app.diagnoseRow(S.rows);
    end

    % Button pushed function: DiagnoseValuesButton
    function DiagnoseValuesButtonPushed(app, event)
      % Diagnose the five gas values typed in the table at the left (ppm); nothing is stored or changed (RD)
      T = app.GasTable.Data;
      v = double(T{:, 2});
      if numel(v) ~= 5 || any(~isfinite(v)) || any(v < 0)
          app.StatusLabel.Text = 'Type five gas values in ppm (numbers, not below 0) in the table, then press the button again.';
          return
      end
      g = struct('H2', v(1), 'CH4', v(2), 'C2H6', v(3), 'C2H4', v(4), 'C2H2', v(5), 'record_id', "typed values", 'equipment_type', "");
      app.setBusy(true);  cleanBusy = onCleanup(@() app.setBusy(false));
      app.diagnoseGas(g, "", 0);
    end

    % Button pushed function: RestartServiceButton
    function RestartServiceButtonPushed(app, event)
      % Master control: stop the ML service (if it runs, also a hung one) and start it again (MC)
      app.setBusy(true);  cleanBusy = onCleanup(@() app.setBusy(false));
      dlg = uiprogressdlg(app.UIFigure, 'Title', 'ML service', 'Message', 'Stopping the ML service...', 'Indeterminate', 'on');
      cleanDlg = onCleanup(@() delete(dlg));
      app.setProgress(dlg, 'Stopping the ML service...');
      stop_ml_service();  pause(1);
      [mlOK, mlMsg, started] = ensure_ml_service(app.ProjRoot, 60, @(s) app.setProgress(dlg, s));
      if started, app.ServiceStarted = true; end
      if mlOK
          app.StatusLabel.Text = 'ML service restarted and connected.';
      else
          app.StatusLabel.Text = ['ML NOT connected: ' mlMsg];
      end
    end

    % Cell selection function: EventTable
    function EventTableCellSelection(app, event)
      % Click a sampling event: show its diagnosis and its explanation again (MC)
      if isempty(event.Indices), return, end
      k = event.Indices(1, 1);
      if k < 1 || k > numel(app.EventResults), return, end
      R = app.EventResults(k).result;
      app.LastResult = R;
      app.RecordSpinner.Value = app.EventResults(k).row;
      app.ExplainState.focus = "diag";
      app.showResult(R, app.EventResults(k).row);
      app.StatusLabel.Text = sprintf('Showing sampling event %d: record %s.', k, char(R.record_id));
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      % Closing the dashboard stops the ML service when this app started it (MC)
      try
          if app.ServiceStarted, stop_ml_service(); end
      catch
      end
      delete(app);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'Transformer DGA Diagnostic Dashboard';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.ControlPanel = uipanel(app.GridLayout);
      app.ControlPanel.Title = 'Controls';
      app.ControlGrid = uigridlayout(app.ControlPanel);
      app.Step1Label = uilabel(app.ControlGrid);
      app.Step1Label.Text = '1  Operating condition';
      app.ScenarioDropDown = uidropdown(app.ControlGrid);
      app.ScenarioDropDown.Items = {'Constant rated load', 'Daily load cycle', 'Short overload'};
      app.ScenarioDropDown.ItemsData = {'rated', 'daily', 'overload'};
      app.ScenarioDropDown.Value = 'rated';
      app.Step2Label = uilabel(app.ControlGrid);
      app.Step2Label.Text = '2  Oil samples to examine';
      app.SampleSetDropDown = uidropdown(app.ControlGrid);
      app.SampleSetDropDown.Items = {'Mixed: healthy + every fault type (7)', 'Healthy records only (Normal class)', 'Random held-out records (7)', 'First 4 held-out records (test run)'};
      app.SampleSetDropDown.ItemsData = {'mixed', 'healthy', 'random', 'first'};
      app.SampleSetDropDown.Value = 'mixed';
      app.RunSimulationButton = uibutton(app.ControlGrid, 'push');
      app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
      app.RunSimulationButton.Text = 'Run simulation + diagnose';
      app.Step3Label = uilabel(app.ControlGrid);
      app.Step3Label.Text = 'Or examine one record';
      app.LoadDGAFileButton = uibutton(app.ControlGrid, 'push');
      app.LoadDGAFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDGAFileButtonPushed, true);
      app.LoadDGAFileButton.Text = 'Load measured DGA file';
      app.FaultFilterDropDown = uidropdown(app.ControlGrid);
      app.FaultFilterDropDown.Items = {'Any record', 'Healthy (Normal)', 'PD partial discharge', 'D1 low-energy discharge', 'D2 high-energy discharge', 'T1 thermal below 300 C', 'T2 thermal 300-700 C', 'T3 thermal above 700 C'};
      app.FaultFilterDropDown.ItemsData = {'All', 'Normal', 'PD', 'D1', 'D2', 'T1', 'T2', 'T3'};
      app.FaultFilterDropDown.Value = 'All';
      app.RandomRecordButton = uibutton(app.ControlGrid, 'push');
      app.RandomRecordButton.ButtonPushedFcn = createCallbackFcn(app, @RandomRecordButtonPushed, true);
      app.RandomRecordButton.Text = 'Pick random record';
      app.RecordSpinnerLabel = uilabel(app.ControlGrid);
      app.RecordSpinnerLabel.Text = 'Record number (row)';
      app.RecordSpinner = uispinner(app.ControlGrid);
      app.RecordSpinner.Limits = [1 1];
      app.RecordSpinner.RoundFractionalValues = 'on';
      app.RecordSpinner.Value = 1;
      app.DiagnoseButton = uibutton(app.ControlGrid, 'push');
      app.DiagnoseButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseButtonPushed, true);
      app.DiagnoseButton.Text = 'Diagnose selected record';
      app.GasTable = uitable(app.ControlGrid);
      app.GasTable.ColumnName = {'Gas'; 'ppm (type here)'};
      app.GasTable.ColumnEditable = [false true];
      app.GasTable.RowName = {};
      app.DiagnoseValuesButton = uibutton(app.ControlGrid, 'push');
      app.DiagnoseValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseValuesButtonPushed, true);
      app.DiagnoseValuesButton.Text = 'Diagnose the values typed above';
      app.RestartServiceButton = uibutton(app.ControlGrid, 'push');
      app.RestartServiceButton.ButtonPushedFcn = createCallbackFcn(app, @RestartServiceButtonPushed, true);
      app.RestartServiceButton.Text = 'Restart ML service';
      app.StatusLabel = uilabel(app.ControlGrid);
      app.StatusLabel.Text = '';
      app.RightGrid = uigridlayout(app.GridLayout);
      app.VerdictPanel = uipanel(app.RightGrid);
      app.VerdictGrid = uigridlayout(app.VerdictPanel);
      app.VerdictLabel = uilabel(app.VerdictGrid);
      app.VerdictLabel.Text = 'WAITING FOR A SAMPLE';
      app.VerdictDetail = uilabel(app.VerdictGrid);
      app.VerdictDetail.Text = '';
      app.VerdictAction = uilabel(app.VerdictGrid);
      app.VerdictAction.Text = '';
      app.AgreementGrid = uigridlayout(app.VerdictGrid);
      app.AgreementLamp = uilamp(app.AgreementGrid);
      app.AgreementLabel = uilabel(app.AgreementGrid);
      app.AgreementLabel.Text = 'Agreement: not available';
      app.CardsGrid = uigridlayout(app.RightGrid);
      app.RogersCard = uipanel(app.CardsGrid);
      app.RogersCard.Title = 'Rogers ratio (IEEE C57.104)';
      app.RogersGrid = uigridlayout(app.RogersCard);
      app.RogersAnswer = uilabel(app.RogersGrid);
      app.RogersAnswer.Text = '-';
      app.RogersNote = uilabel(app.RogersGrid);
      app.RogersNote.Text = '';
      app.RogersJudge = uilabel(app.RogersGrid);
      app.RogersJudge.Text = '';
      app.IECCard = uipanel(app.CardsGrid);
      app.IECCard.Title = 'IEC 60599 ratio method';
      app.IECGrid = uigridlayout(app.IECCard);
      app.IECAnswer = uilabel(app.IECGrid);
      app.IECAnswer.Text = '-';
      app.IECNote = uilabel(app.IECGrid);
      app.IECNote.Text = '';
      app.IECJudge = uilabel(app.IECGrid);
      app.IECJudge.Text = '';
      app.DuvalCard = uipanel(app.CardsGrid);
      app.DuvalCard.Title = 'Duval Triangle 1';
      app.DuvalGrid = uigridlayout(app.DuvalCard);
      app.DuvalAnswer = uilabel(app.DuvalGrid);
      app.DuvalAnswer.Text = '-';
      app.DuvalNote = uilabel(app.DuvalGrid);
      app.DuvalNote.Text = '';
      app.DuvalJudge = uilabel(app.DuvalGrid);
      app.DuvalJudge.Text = '';
      app.MLCard = uipanel(app.CardsGrid);
      app.MLCard.Title = 'Machine learning (Random Forest)';
      app.MLGrid = uigridlayout(app.MLCard);
      app.MLAnswer = uilabel(app.MLGrid);
      app.MLAnswer.Text = '-';
      app.MLNote = uilabel(app.MLGrid);
      app.MLNote.Text = '';
      app.MLJudge = uilabel(app.MLGrid);
      app.MLJudge.Text = '';
      app.RefCard = uipanel(app.CardsGrid);
      app.RefCard.Title = 'Known label of the record';
      app.RefGrid = uigridlayout(app.RefCard);
      app.RefAnswer = uilabel(app.RefGrid);
      app.RefAnswer.Text = '-';
      app.RefNote = uilabel(app.RefGrid);
      app.RefNote.Text = '';
      app.RefJudge = uilabel(app.RefGrid);
      app.RefJudge.Text = '';
      app.EventTable = uitable(app.RightGrid);
      app.EventTable.ColumnName = {'#'; 'Time h'; 'Record'; 'Known label'; 'Rogers'; 'IEC 60599'; 'Duval'; 'ML'; 'Agree of 3'; 'Verdict'; 'Hot-spot degC'};
      app.EventTable.RowName = {};
      app.EventTable.CellSelectionCallback = createCallbackFcn(app, @EventTableCellSelection, true);
      app.MainTabs = uitabgroup(app.RightGrid);
      app.ChartsTab = uitab(app.MainTabs);
      app.ChartsTab.Title = 'Charts';
      app.ChartsGrid = uigridlayout(app.ChartsTab);
      app.GasAxes = uiaxes(app.ChartsGrid);
      title(app.GasAxes, 'Gas level compared with the typical value');
      app.UIAxesDuval = uiaxes(app.ChartsGrid);
      title(app.UIAxesDuval, 'Duval Triangle 1 (this sample)');
      app.ProbAxes = uiaxes(app.ChartsGrid);
      title(app.ProbAxes, 'ML probability of each fault type');
      app.StepsTab = uitab(app.MainTabs);
      app.StepsTab.Title = 'Step by step';
      app.StepsGrid = uigridlayout(app.StepsTab);
      app.StepsArea = uitextarea(app.StepsGrid);
      app.StepsArea.Editable = 'off';
      app.ExplainTab = uitab(app.MainTabs);
      app.ExplainTab.Title = 'Explanation in words';
      app.ExplainGrid = uigridlayout(app.ExplainTab);
      app.ExplainArea = uitextarea(app.ExplainGrid);
      app.ExplainArea.Editable = 'off';
      app.TablesTab = uitab(app.MainTabs);
      app.TablesTab.Title = 'Tables';
      app.TablesGrid = uigridlayout(app.TablesTab);
      app.ResultsTable = uitable(app.TablesGrid);
      app.ResultsTable.ColumnName = {'Method'; 'Diagnosis'; 'Agrees with ML'};
      app.ResultsTable.RowName = {};
      app.ProbabilityTable = uitable(app.TablesGrid);
      app.ProbabilityTable.ColumnName = {'Class'; 'Probability'};
      app.ProbabilityTable.RowName = {};
      app.ModelLabel = uilabel(app.TablesGrid);
      app.ModelLabel.Text = 'ML: not called yet';
      app.SimTab = uitab(app.MainTabs);
      app.SimTab.Title = 'Simulation (operating condition)';
      app.SimGrid = uigridlayout(app.SimTab);
      app.UIAxesTemperature = uiaxes(app.SimGrid);
      title(app.UIAxesTemperature, 'Transformer operating condition (simulated, context only)');
      xlabel(app.UIAxesTemperature, 'Time (h)');
      ylabel(app.UIAxesTemperature, 'Temperature (\circC)');
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DGADashboard

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end