classdef DGADashboard < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    AgreementLabel           matlab.ui.control.Label
    AgreementLamp            matlab.ui.control.Lamp
    AgreementGrid            matlab.ui.container.GridLayout
    ModelLabel               matlab.ui.control.Label
    BottomGrid               matlab.ui.container.GridLayout
    EventTable               matlab.ui.control.Table
    ProbabilityTable         matlab.ui.control.Table
    ResultsTable             matlab.ui.control.Table
    RightGrid                matlab.ui.container.GridLayout
    UIAxesDuval              matlab.ui.control.UIAxes
    UIAxesTemperature        matlab.ui.control.UIAxes
    StatusLabel              matlab.ui.control.Label
    GasTable                 matlab.ui.control.Table
    DiagnoseButton           matlab.ui.control.Button
    RecordSpinner            matlab.ui.control.Spinner
    RecordSpinnerLabel       matlab.ui.control.Label
    LoadDGAFileButton        matlab.ui.control.Button
    RunSimulationButton      matlab.ui.control.Button
    ControlGrid              matlab.ui.container.GridLayout
    ControlPanel             matlab.ui.container.Panel
    GridLayout               matlab.ui.container.GridLayout
    UIFigure                 matlab.ui.Figure
  end

    properties (Access = public)
        DGAFile        % full path of the measured DGA file
        ServiceURL     % address of the FastAPI service (D37)
        LastResult     % struct of the last diagnosis: record_id, gas, conventional, ml, agreement (D37)
        SimEvents      % table of the sampling events of the last simulation: time_h, record_index, record_id (D38)
        EventResults   % struct array, one element per sampling event diagnosed in the last run (D38)
        PlaybackPause = 0.5  % pause between sampling events in the playback, s (0 in tests) (D38)
    end

    properties (Access = private)
        SimResult   % struct returned by run_transformer_simulation
        Gas         % struct with H2, CH4, C2H6, C2H4, C2H2 of the current record
        Info        % table row of the current record (traceability)
    end

    methods (Access = private)

        % Lamp and text of the agreement flag (Build Guide C4.5; rule in dga_agreement.m, D37)
        function updateAgreement(app, a)
            app.AgreementLamp.Color = a.color;
            app.AgreementLabel.Text = a.label;
        end

        % Diagnose one row of the loaded DGA file: the one code path of the Diagnose button and of the sampling events (D38)
        function ok = diagnoseRow(app, row)
            ok = false;
            try
                [app.Gas, app.Info] = load_dga_record(app.DGAFile, row);
            catch ME
                app.StatusLabel.Text = ['Cannot read the record: ' ME.message];
                return
            end
            g = app.Gas;
            app.StatusLabel.Text = 'Diagnosing...'; drawnow;
            d = conventional_diagnosis(g);                 % three conventional diagnoses, computed here in MATLAB
            m = ml_diagnose(g, app.ServiceURL);            % ML diagnosis from the FastAPI service (webwrite)
            if m.status == "NOT_CONNECTED"                 % service stopped meanwhile: start it again and retry once
                app.StatusLabel.Text = 'ML service not reachable: starting it...'; drawnow;
                if ensure_ml_service(fileparts(fileparts(which('conventional_diagnosis'))))
                    m = ml_diagnose(g, app.ServiceURL);
                end
            end
            a = dga_agreement(d, m);
            app.GasTable.Data = table(["H2"; "CH4"; "C2H6"; "C2H4"; "C2H2"], ...
                [g.H2; g.CH4; g.C2H6; g.C2H4; g.C2H2], 'VariableNames', {'Gas', 'ppm'});
            mlText = m.status;
            if m.status == "OK", mlText = m.fault_class; end
            yesno = repmat("-", 3, 1);
            if m.status == "OK", yesno(a.agrees) = "yes"; yesno(~a.agrees) = "no"; end
            app.ResultsTable.Data = table(["Rogers Ratio"; "IEC 60599 ratio"; "Duval Triangle 1"; "Machine learning"], ...
                [string(d.rogers); string(d.iec); string(d.duval); mlText], [yesno; "-"], ...
                'VariableNames', {'Method', 'Diagnosis', 'Agrees with ML'});
            if m.status == "OK"
                app.ProbabilityTable.Data = table(m.classes(:), m.probabilities(:), 'VariableNames', {'Class', 'Probability'});
                app.ModelLabel.Text = sprintf('ML: %s v%s (hash %s), confidence %.3f, %.0f ms', ...
                    m.model_name, m.model_version, extractBefore(m.model_sha256, 9), m.confidence, 1000 * m.latency_s);
            else
                app.ProbabilityTable.Data = table(strings(0, 1), zeros(0, 1), 'VariableNames', {'Class', 'Probability'});
                app.ModelLabel.Text = ['ML: ' char(m.status)];
            end
            root = fileparts(fileparts(which('conventional_diagnosis')));
            try
                plot_duval_triangle(app.UIAxesDuval, d.ratios, fullfile(root, '02_Standards', 'duval_triangle_zones.csv'));
            catch ME
                cla(app.UIAxesDuval);
                app.StatusLabel.Text = ['Duval Triangle not drawn: ' ME.message];
            end
            app.updateAgreement(a);
            rid = ""; if isfield(g, 'record_id'), rid = g.record_id; end
            app.LastResult = struct('record_id', rid, 'gas', g, 'conventional', d, 'ml', m, 'agreement', a);
            if startsWith(app.StatusLabel.Text, 'Diagnosing')
                app.StatusLabel.Text = sprintf('Record %d %s diagnosed.', row, char(rid));
            end
            ok = true;
        end

        % Play the sampling events of the last simulation back in time order and diagnose the record of each (D38)
        function playEvents(app, baseText)
            ev = app.SimEvents;  r = app.SimResult;
            app.EventResults = struct('event', {}, 'time_h', {}, 'record_id', {}, 'row', {}, 'hot_spot_C', {}, 'result', {});
            app.EventTable.Data = table(zeros(0, 1), zeros(0, 1), strings(0, 1), strings(0, 1), strings(0, 1), zeros(0, 1), ...
                'VariableNames', {'Event', 'Time (h)', 'Record', 'ML', 'Agree (of 3)', 'Hot-spot (°C)'});
            if isempty(ev)
                app.StatusLabel.Text = [baseText ' No sampling events in this run.'];
                return
            end
            if isempty(app.DGAFile)
                root = fileparts(fileparts(which('conventional_diagnosis')));
                app.DGAFile = fullfile(root, '03_Data', 'processed', 'dga_master.csv');
                baseText = [baseText ' (No DGA file loaded: using dga_master.csv.)'];
            end
            nrec = height(readtable(app.DGAFile));
            app.RecordSpinner.Limits = [1 nrec];
            tbl = app.EventTable.Data;  nok = 0;
            for k = 1:height(ev)
                row = find_dga_row(app.DGAFile, ev.record_id(k));
                if isnan(row)
                    app.StatusLabel.Text = sprintf('Event %d: record %s is not in the DGA file.', k, char(ev.record_id(k)));
                    continue
                end
                app.RecordSpinner.Value = row;
                app.StatusLabel.Text = sprintf('Sampling event %d at %.1f h: diagnosing %s...', k, ev.time_h(k), char(ev.record_id(k))); drawnow;
                if ~app.diagnoseRow(row), continue, end
                R = app.LastResult;  hs = interp1(r.t_h, r.theta_hs_C, ev.time_h(k));
                mlText = R.ml.status;  agr = "-";
                if R.ml.status == "OK", mlText = R.ml.fault_class; agr = string(R.agreement.count); end
                tbl(end + 1, :) = {k, ev.time_h(k), ev.record_id(k), mlText, agr, hs}; %#ok<AGROW>
                app.EventTable.Data = tbl;
                app.EventResults(end + 1) = struct('event', k, 'time_h', ev.time_h(k), 'record_id', ev.record_id(k), 'row', row, 'hot_spot_C', hs, 'result', R);
                nok = nok + 1;
                pause(app.PlaybackPause);
            end
            app.StatusLabel.Text = sprintf('%s %d of %d sampling events diagnosed.', baseText, nok, height(ev));
        end
    end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      % The app finds the project folders itself, so it also starts when project_setup has not been run (15 Oct 2026).
      % It looks upwards from the app file, then from the current folder, for the folder that holds project_setup.m.
      projRoot = '';
      starts = {};
      try, starts{end + 1} = fileparts(which('DGADashboard')); catch, end
      try, starts{end + 1} = fileparts(mfilename('fullpath')); catch, end
      starts{end + 1} = pwd;
      for iStart = 1:numel(starts)
          dirNow = starts{iStart};
          while ~isempty(dirNow) && isempty(projRoot)
              if isfile(fullfile(dirNow, 'project_setup.m')), projRoot = dirNow; end
              parentDir = fileparts(dirNow);
              if strcmp(parentDir, dirNow), break, end
              dirNow = parentDir;
          end
          if ~isempty(projRoot), break, end
      end
      if isempty(projRoot)
          app.StatusLabel.Text = 'Project folder not found. In MATLAB open the project folder, run project_setup.m, then start the dashboard again.';
          error('DGADashboard:ProjectNotFound', 'project_setup.m was not found above the app file or the current folder.');
      end
      addpath(fullfile(projRoot, '04_MATLAB_Conventional'), fullfile(projRoot, '05_Simulink_Model'), fullfile(projRoot, '10_Dashboard_Client'));
      % Parameters of the transformer model into the base workspace (Build Guide C4.1)
      evalin('base', 'params_transformer;');
      app.ServiceURL = 'http://127.0.0.1:8000/diagnose';
      % The dashboard is the master control: it starts the ML service itself if it is not running (15 Oct 2026).
      app.StatusLabel.Text = 'Starting the ML service (about 10-30 s)...'; drawnow;
      [mlOK, mlMsg] = ensure_ml_service(projRoot);
      if mlOK
          app.StatusLabel.Text = 'Ready. ML service connected. Run the simulation or load a DGA file.';
      else
          app.StatusLabel.Text = ['Ready, but ML NOT connected: ' mlMsg];
      end
      app.AgreementLamp.Color = [0.6 0.6 0.6];
    end

    % Button pushed function: RunSimulationButton
    function RunSimulationButtonPushed(app, event)
      app.StatusLabel.Text = 'Simulating transformer operation...'; drawnow;
      try
          app.SimResult = run_transformer_simulation();   % sim of Transformer_Operating_Model; also returns the sampling events (D38)
      catch ME
          app.StatusLabel.Text = ['Simulation failed: ' ME.message];
          return
      end
      r = app.SimResult;
      delete(findall(app.UIAxesTemperature, 'Type', 'constantline'));   % event lines of an earlier run (hidden handles are not cleared by plot)
      plot(app.UIAxesTemperature, r.t_h, r.theta_oil_C, 'b', r.t_h, r.theta_hs_C, 'r', 'LineWidth', 1.3);
      xlabel(app.UIAxesTemperature, 'Time (h)'); ylabel(app.UIAxesTemperature, 'Temperature (\circC)');
      legend(app.UIAxesTemperature, 'Top-oil', 'Hot-spot', 'Location', 'southeast');
      grid(app.UIAxesTemperature, 'on');
      app.SimEvents = r.events;
      for k = 1:height(r.events)          % sampling events of SS8, numbered in time order
          xline(app.UIAxesTemperature, r.events.time_h(k), ':', char("#" + k), 'HandleVisibility', 'off');
      end
      baseText = sprintf('Simulation done. Final hot-spot %.1f °C, ageing %.1f h. (Operating context only.)', ...
          r.theta_hs_C(end), r.loss_of_life_h);
      app.StatusLabel.Text = baseText; drawnow;
      app.playEvents(baseText);
    end

    % Button pushed function: LoadDGAFileButton
    function LoadDGAFileButtonPushed(app, event)
      root = fileparts(fileparts(which('conventional_diagnosis')));
      [f, p] = uigetfile({'*.csv;*.xlsx', 'DGA files'}, 'Select measured DGA file', fullfile(root, '03_Data', 'processed'));
      if isequal(f, 0), return, end
      try
          n = height(readtable(fullfile(p, f)));
      catch ME
          app.StatusLabel.Text = ['Cannot read the file: ' ME.message];
          return
      end
      app.DGAFile = fullfile(p, f);
      app.RecordSpinner.Limits = [1 n];
      app.RecordSpinner.Value = 1;
      app.StatusLabel.Text = sprintf('Loaded %s (%d records).', f, n);
    end

    % Button pushed function: DiagnoseButton
    function DiagnoseButtonPushed(app, event)
      if isempty(app.DGAFile)
          app.StatusLabel.Text = 'Load a DGA file first.'; return
      end
      app.diagnoseRow(app.RecordSpinner.Value);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'Transformer DGA Diagnostic Dashboard';
      app.GridLayout = uigridlayout(app.UIFigure);
      app.ControlPanel = uipanel(app.GridLayout);
      app.ControlPanel.Title = 'Controls';
      app.ControlGrid = uigridlayout(app.ControlPanel);
      app.RunSimulationButton = uibutton(app.ControlGrid, 'push');
      app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
      app.RunSimulationButton.Text = 'Run transformer simulation';
      app.LoadDGAFileButton = uibutton(app.ControlGrid, 'push');
      app.LoadDGAFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDGAFileButtonPushed, true);
      app.LoadDGAFileButton.Text = 'Load measured DGA file';
      app.RecordSpinnerLabel = uilabel(app.ControlGrid);
      app.RecordSpinnerLabel.Text = 'Record number (row)';
      app.RecordSpinner = uispinner(app.ControlGrid);
      app.RecordSpinner.Limits = [1 1];
      app.RecordSpinner.RoundFractionalValues = 'on';
      app.RecordSpinner.Value = 1;
      app.DiagnoseButton = uibutton(app.ControlGrid, 'push');
      app.DiagnoseButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseButtonPushed, true);
      app.DiagnoseButton.Text = 'Diagnose selected record';
      app.GasTable = uitable(app.ControlGrid);
      app.GasTable.ColumnName = {'Gas'; 'ppm'};
      app.GasTable.RowName = {};
      app.StatusLabel = uilabel(app.ControlGrid);
      app.StatusLabel.Text = '';
      app.UIAxesTemperature = uiaxes(app.GridLayout);
      title(app.UIAxesTemperature, 'Transformer operating condition (simulated, context only)');
      xlabel(app.UIAxesTemperature, 'Time (h)');
      ylabel(app.UIAxesTemperature, 'Temperature (\circC)');
      app.UIAxesDuval = uiaxes(app.GridLayout);
      title(app.UIAxesDuval, 'Duval Triangle 1 (measured sample)');
      app.RightGrid = uigridlayout(app.GridLayout);
      app.ResultsTable = uitable(app.RightGrid);
      app.ResultsTable.ColumnName = {'Method'; 'Diagnosis'; 'Agrees with ML'};
      app.ResultsTable.RowName = {};
      app.ProbabilityTable = uitable(app.RightGrid);
      app.ProbabilityTable.ColumnName = {'Class'; 'Probability'};
      app.ProbabilityTable.RowName = {};
      app.EventTable = uitable(app.GridLayout);
      app.EventTable.ColumnName = {'Event'; 'Time (h)'; 'Record'; 'ML'; 'Agree (of 3)'; 'Hot-spot (degC)'};
      app.EventTable.RowName = {};
      app.BottomGrid = uigridlayout(app.GridLayout);
      app.ModelLabel = uilabel(app.BottomGrid);
      app.ModelLabel.Text = 'ML: not called yet';
      app.AgreementGrid = uigridlayout(app.BottomGrid);
      app.AgreementLamp = uilamp(app.AgreementGrid);
      app.AgreementLabel = uilabel(app.AgreementGrid);
      app.AgreementLabel.Text = 'Agreement: not available';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DGADashboard

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end